classdef FirstUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        stfloorelevatorPanel  matlab.ui.container.Panel
        UPButton              matlab.ui.control.Button
        EditField_1           matlab.ui.control.NumericEditField
        EditField_2           matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        MemoryTimer
        pro
        s
        T
        lui
        rui
        db% Description
    end
    
    methods (Access = public)
        function Tim(app,~,~)
            [~,~] = memory;
            app.pro.Timer;
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pro=processor;
            app.s=SecondUI;
            app.T=ThirdUI;
            app.lui=LeftElevatorUI;
            app.rui=RightElevatorUI;
            app.db=database;
            
            
            app.pro.firstui=app;
            app.pro.secondui=app.s;
            app.pro.thirdui=app.T;
            app.pro.leftelevator=app.lui;
            app.pro.rightelevator=app.rui;
            app.pro.Database=app.db;
            
            
            app.s.processor=app.pro;
            app.T.processor=app.pro;
            app.lui.processor=app.pro;
            app.rui.processor=app.pro;
            
            app.db.Pro=app.pro;
            
            app.pro.init;
            
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 1 second
                'TimerFcn', @app.Tim);    % Specify callback function

%             a=timer('TimerFcn','app.pro.Timer;','Period',0.1,'ExecutionMode','fixedRate');
            start(app.MemoryTimer);            
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            app.pro.add_down_waiting(1);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.stfloorelevatorPanel = uipanel(app.UIFigure);
            app.stfloorelevatorPanel.Title = '1st floor elevator';
            app.UPButton = uibutton(app.stfloorelevatorPanel, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.EditField_1 = uieditfield(app.stfloorelevatorPanel, 'numeric');
            app.EditField_2 = uieditfield(app.stfloorelevatorPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FirstUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end