classdef LeftElevatorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Left_Elevator_UIPanel  matlab.ui.container.Panel
        Button_2               matlab.ui.control.Button
        Button_1               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        OpenButton             matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        FloorGaugeLabel        matlab.ui.control.Label
        FloorGauge             matlab.ui.control.SemicircularGauge
        StopButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        processor % Description
    end
    
    methods (Access = public)
        
        function dis(app,num)
            app.FloorGauge.Value=num;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.processor.leftopen_button;
            %app.Lamp.Color = [0,0,1];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.processor.leftclose_button;
            %app.Lamp.Color = [0,1,0];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.processor.left_elev_button(2);            
            %app.FloorGauge.Value = 2;
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.processor.left_elev_button(1);
            %app.FloorGauge.Value = 1;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.processor.left_elev_button(3);
            app.FloorGauge.Value = 2;
            pause(1);
            app.FloorGauge.Value = 3;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.processor.left_stop_button;
            app.Lamp.Color = [1,1,1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Left_Elevator_UIPanel = uipanel(app.UIFigure);
            app.Left_Elevator_UIPanel.Title = 'Left_Elevator_UI';
            app.Button_2 = uibutton(app.Left_Elevator_UIPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_1 = uibutton(app.Left_Elevator_UIPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_3 = uibutton(app.Left_Elevator_UIPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.OpenButton = uibutton(app.Left_Elevator_UIPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.Left_Elevator_UIPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Lamp = uilamp(app.Left_Elevator_UIPanel);
            app.FloorGaugeLabel = uilabel(app.Left_Elevator_UIPanel);
            app.FloorGaugeLabel.Text = 'Floor';
            app.FloorGauge = uigauge(app.Left_Elevator_UIPanel, 'semicircular');
            app.FloorGauge.Limits = [1 3];
            app.FloorGauge.MajorTicks = [1 2 3];
            app.FloorGauge.MinorTicks = [1 2 3];
            app.FloorGauge.Value = 1;
            app.StopButton = uibutton(app.Left_Elevator_UIPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeftElevatorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end