classdef UserUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        GameLevelButton        matlab.ui.control.Button
        QuitButton             matlab.ui.control.Button
        RandomButton           matlab.ui.control.Button
        RestartButton          matlab.ui.control.Button
        Text1                  matlab.ui.control.TextArea
        upButton               matlab.ui.control.Button
        downButton             matlab.ui.control.Button
        leftButton             matlab.ui.control.Button
        rightButton            matlab.ui.control.Button
        HintButton             matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        Panel                  matlab.ui.container.Panel
        Button3                matlab.ui.control.Button
        Button7_4              matlab.ui.control.Button
        Button7_3              matlab.ui.control.Button
        Button2                matlab.ui.control.Button
        Button4                matlab.ui.control.Button
        Button6                matlab.ui.control.Button
        Button5                matlab.ui.control.Button
        Button7_1              matlab.ui.control.Button
        Button1                matlab.ui.control.Button
        Button7_2              matlab.ui.control.Button
        StepNumEditFieldLabel  matlab.ui.control.Label
        StepNumEditField       matlab.ui.control.NumericEditField
        TextArea_2             matlab.ui.control.TextArea
        Test                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        movementProcessor % Description
        hint
        level
    end
   
    
    methods (Access = public)        
        function displayMessage(app,Message)
            app.Text1.Value=Message;
        end
        
        function choose_level(app,num)
            app.movementProcessor.level(num);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GameLevelButton
        function GameLevelButtonPushed(app, event)
            app.level=Level; 
            app.level.UI=app;            
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            app.movementProcessor.button(6);
        end

        % Button pushed function: Button7_3
        function Button7_3Pushed(app, event)
            app.movementProcessor.button(73);            
        end

        % Button pushed function: Button7_1
        function Button7_1Pushed(app, event)
            app.movementProcessor.button(71); 
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            quit;
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            app.movementProcessor.button(1);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.movementProcessor.restart;
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            app.movementProcessor.button(2);
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            if app.movementProcessor.GameDB.block~=0 && app.movementProcessor.GameDB.up~=0
                app.movementProcessor.up(app.movementProcessor.GameDB.block);
            end
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            if app.movementProcessor.GameDB.block~=0 && app.movementProcessor.GameDB.down~=0
                app.movementProcessor.down(app.movementProcessor.GameDB.block);
            end
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            if app.movementProcessor.GameDB.block~=0 && app.movementProcessor.GameDB.left~=0
                app.movementProcessor.left(app.movementProcessor.GameDB.block);
            end
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            if app.movementProcessor.GameDB.block~=0 && app.movementProcessor.GameDB.right~=0
                app.movementProcessor.right(app.movementProcessor.GameDB.block);
            end
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            app.movementProcessor.button(3);
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            app.movementProcessor.button(4);
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            app.movementProcessor.button(5);            
        end

        % Button pushed function: Button7_2
        function Button7_2Pushed(app, event)
            app.movementProcessor.button(72);                    
        end

        % Button pushed function: Button7_4
        function Button7_4Pushed(app, event)
            app.movementProcessor.button(74);  
        end

        % Button pushed function: RandomButton
        function RandomButtonButtonPushed(app, event)
            app.movementProcessor.random;
        end

        % Button pushed function: HintButton
        function HintButtonPushed(app, event)
            app.hint=Hint;
            app.movementProcessor.hint;            
        end

        % Button pushed function: Test
        function TestButtonPushed(app, event)
            app.movementProcessor.init(0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Huarong Path'};
            app.GameLevelButton = uibutton(app.LeftPanel, 'push');
            app.GameLevelButton.ButtonPushedFcn = createCallbackFcn(app, @GameLevelButtonPushed, true);
            app.GameLevelButton.Text = 'Game Level';
            app.QuitButton = uibutton(app.LeftPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.RandomButton = uibutton(app.LeftPanel, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.Text1 = uitextarea(app.LeftPanel);
            app.Text1.Value = {'Welcome to Huarong Path! '};
            app.upButton = uibutton(app.LeftPanel, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Text = 'up';
            app.downButton = uibutton(app.LeftPanel, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = 'down';
            app.leftButton = uibutton(app.LeftPanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.rightButton = uibutton(app.LeftPanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.HintButton = uibutton(app.LeftPanel, 'push');
            app.HintButton.ButtonPushedFcn = createCallbackFcn(app, @HintButtonPushed, true);
            app.HintButton.Text = 'Hint';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.Button3 = uibutton(app.Panel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '马超';
            app.Button7_4 = uibutton(app.Panel, 'push');
            app.Button7_4.ButtonPushedFcn = createCallbackFcn(app, @Button7_4Pushed, true);
            app.Button7_4.Text = '卒';
            app.Button7_3 = uibutton(app.Panel, 'push');
            app.Button7_3.ButtonPushedFcn = createCallbackFcn(app, @Button7_3Pushed, true);
            app.Button7_3.Text = '卒';
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '张飞';
            app.Button4 = uibutton(app.Panel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '黄忠';
            app.Button6 = uibutton(app.Panel, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '关羽';
            app.Button5 = uibutton(app.Panel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '赵云';
            app.Button7_1 = uibutton(app.Panel, 'push');
            app.Button7_1.ButtonPushedFcn = createCallbackFcn(app, @Button7_1Pushed, true);
            app.Button7_1.Text = '卒';
            app.Button1 = uibutton(app.Panel, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '曹操';
            app.Button7_2 = uibutton(app.Panel, 'push');
            app.Button7_2.ButtonPushedFcn = createCallbackFcn(app, @Button7_2Pushed, true);
            app.Button7_2.Text = '卒';
            app.StepNumEditFieldLabel = uilabel(app.RightPanel);
            app.StepNumEditFieldLabel.Text = 'Step Num';
            app.StepNumEditField = uieditfield(app.RightPanel, 'numeric');
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Value = {'横刀立马'};
            app.Test = uibutton(app.RightPanel, 'push');
            app.Test.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.Test.Text = 'Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end