classdef PatientUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        MessagePanel   matlab.ui.container.Panel
        Message        matlab.ui.control.Label
        RequestButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        DB % Description
        Pro
        physician
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RequestButton.Enable = 1;
            app.Message.Text = 'Welcome to Painkiller injection system 2.0 !';
        end

        % Button pushed function: RequestButton
        function RequestButtonPushed(app, event)
            app.Pro.displayMessage('You are requesting a bolus shot.','Patient');
            [record,result] = app.Pro.createRecord('Bolus',app.physician.BoluPK);
            switch result
                case 'Fail'
                    app.Pro.displayMessage('Request failed: Get too much painkiller.','Patient');
                    app.RequestButton.Enable = 1;
                case 'Success'
                    app.Pro.displayMessage('Request successfully.','Patient');
                    app.DB.getStart(record);
                    app.Pro.displayMessage('Injection Complete.','Patient');
                    if app.physician.time < 10 && app.physician.count < 10
                        record.finishTime = sprintf('0%d:0%d',app.physician.time,app.physician.count);
                    elseif app.physician.time >= 10 && app.physician.count < 10
                        record.finishTime = sprintf('%d:0%d',app.physician.time,app.physician.count);
                    elseif app.physician.time < 10 && app.physician.count >= 10
                        record.finishTime = sprintf('0%d:%d',app.physician.time,app.physician.count);
                    else
                        record.finishTime = sprintf('%d:%d',app.physician.time,app.physician.count);
                    end
                    app.DB.regist(record);
                    app.physician.RecordList.Data = [app.physician.RecordList.Data;{record.way,record.startTime,record.finishTime,record.painkillerAmount,'Complete'}];
                    app.RequestButton.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MessagePanel = uipanel(app.UIFigure);
            app.MessagePanel.Title = 'MessagePanel';
            app.Message = uilabel(app.MessagePanel);
            app.Message.Text = '';
            app.RequestButton = uibutton(app.UIFigure, 'push');
            app.RequestButton.ButtonPushedFcn = createCallbackFcn(app, @RequestButtonPushed, true);
            app.RequestButton.Text = 'Request';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end