classdef PhysicianUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BaselineamountEditFieldLabel  matlab.ui.control.Label
        BaselineamountEditField       matlab.ui.control.NumericEditField
        BolusamountEditFieldLabel     matlab.ui.control.Label
        BolusamountEditField          matlab.ui.control.NumericEditField
        MessagePanel                  matlab.ui.container.Panel
        Message                       matlab.ui.control.Label
        RecordList                    matlab.ui.control.Table
        AmountDayPanel                matlab.ui.container.Panel
        AmountD                       matlab.ui.control.Label
        AmountHourPanel               matlab.ui.container.Panel
        AmountH                       matlab.ui.control.Label
        BaselinePKPanel               matlab.ui.container.Panel
        BaselinePK                    matlab.ui.control.Label
        BolusPKPanel                  matlab.ui.container.Panel
        BolusPK                       matlab.ui.control.Label
        ConfirmButton                 matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ChangeButton                  matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        DB % Description
        Pro
        patient
        BasePK = 0.01
        BoluPK = 0.2
        PKperday = 0
        PKperhour = 0
        
        permit = 1
        
        CheckTimer
        time = 0
        CountTimer
        count = 0
        
        testDelay = 60
        testTasks = 24
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AmountD.Text = '0.00 ml';
            app.AmountH.Text = '0.00 ml';
            app.Message.Text = 'Welcome to Painkiller injection system 2.0 !';
            app.BaselinePK.Text = '0.01 ml/min';
            app.BolusPK.Text = '0.20 ml/shot';
            app.ConfirmButton.Enable = 1;
            app.StartButton.Enable = 0;
            app.ChangeButton.Enable = 0;
   
%             app.CheckTimer = timer('TimerFcn',@t_TimerFcn, 'Period', app.testDelay,'ExecutionMode', 'fixedSpacing', 'TasksToExecute', app.testTasks);
%             app.CheckTimer.TimerFcn = @(~, ~) timer_Check(app);
%             app.CheckTimer.StartDelay = app.testDelay;
%             Timer for testcases 
            app.CheckTimer = timer('TimerFcn',@t_TimerFcn, 'Period', 10,'ExecutionMode', 'fixedSpacing', 'TasksToExecute', 2);
            app.CheckTimer.TimerFcn = @(~, ~) timer_Check(app);
            app.CheckTimer.StartDelay = 25;

            app.CountTimer = timer('TimerFcn',@t_timerFcn,'Period', 1,'ExecutionMode', 'fixedSpacing','TasksToExecute',app.testTasks*app.testDelay);
            app.CountTimer.TimerFcn = @(~,~) timer_Count(app);
            app.CountTimer.StartDelay = 0;
            
            start(app.CountTimer);
            start(app.CheckTimer);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.BoluPK = app.BolusamountEditField.Value;
            app.ChangeButton.Enable = 0;
            app.BolusPK.Text = sprintf('%.2f ml/shot',app.BolusamountEditField.Value);
            app.Pro.displayMessage('The bolus amount of painkiller has been changed.','Physician');
        end

        % Value changed function: BolusamountEditField
        function BolusamountEditFieldValueChanged(app, event)
            app.ChangeButton.Enable = 1;
        end

        % Value changed function: BaselineamountEditField
        function BaselineamountEditFieldValueChanged(app, event)
            app.ConfirmButton.Enable = 1;
            app.StartButton.Enable = 0;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.BasePK = app.BaselineamountEditField.Value;
            app.StartButton.Enable = 1;
            app.ConfirmButton.Enable = 0;
            app.BaselinePK.Text = sprintf('%.2f ml/min',app.BaselineamountEditField.Value);
            app.Pro.displayMessage('The baseline amount of painkiller has been changed.','Physician');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [record,result] = app.Pro.createRecord('Baseline',app.BasePK);
            switch result
                case 'Fail'
                    app.Pro.displayMessage(sprintf('Operation failed: Get too much painkiller.\n You can try to modify the "BaselinePK" to restart.'),'Physician');
                    app.ConfirmButton.Enable = 0;
                    app.StartButton.Enable = 1;
                case 'Success'
                    app.Pro.displayMessage('Regist Complete.','Physician');
                    app.RecordList.Data = [app.RecordList.Data;{record.way,record.startTime,'--:--',record.painkillerAmount,'Undergoing'}];
                    app.DB.getStart(record);
                    if app.time < 10 && app.count < 10
                        record.finishTime = sprintf('0%d:0%d',app.time,app.count);
                    elseif app.time >= 10 && app.count < 10
                        record.finishTime = sprintf('%d:0%d',app.time,app.count);
                    elseif app.time < 10 && app.count >= 10
                        record.finishTime = sprintf('0%d:%d',app.time,app.count);
                    else
                        record.finishTime = sprintf('%d:%d',app.time,app.count);
                    end
                    app.DB.regist(record);
                    app.Pro.displayMessage('Injection Complete.','Physician');
                    app.RecordList.Data{size(app.RecordList.Data,1),end} = 'Complete';
                    app.RecordList.Data{size(app.RecordList.Data,1),3} = record.finishTime;
                    app.ConfirmButton.Enable = 0;
                    app.StartButton.Enable = 1;
                    app.permit = 1;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.permit = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BaselineamountEditFieldLabel = uilabel(app.UIFigure);
            app.BaselineamountEditFieldLabel.Text = 'Baseline amount';
            app.BaselineamountEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselineamountEditField.Limits = [0.01 0.1];
            app.BaselineamountEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineamountEditFieldValueChanged, true);
            app.BaselineamountEditField.Value = 0.01;
            app.BolusamountEditFieldLabel = uilabel(app.UIFigure);
            app.BolusamountEditFieldLabel.Text = 'Bolus amount';
            app.BolusamountEditField = uieditfield(app.UIFigure, 'numeric');
            app.BolusamountEditField.Limits = [0.2 0.5];
            app.BolusamountEditField.ValueChangedFcn = createCallbackFcn(app, @BolusamountEditFieldValueChanged, true);
            app.BolusamountEditField.Value = 0.2;
            app.MessagePanel = uipanel(app.UIFigure);
            app.MessagePanel.Title = 'Message';
            app.Message = uilabel(app.MessagePanel);
            app.Message.Text = '';
            app.RecordList = uitable(app.UIFigure);
            app.RecordList.ColumnName = {'Way'; 'StartTime'; 'FinishTime'; 'PainkillerAmount'; 'Status'};
            app.RecordList.RowName = {};
            app.AmountDayPanel = uipanel(app.UIFigure);
            app.AmountDayPanel.Title = 'Amount/Day';
            app.AmountD = uilabel(app.AmountDayPanel);
            app.AmountD.Text = '';
            app.AmountHourPanel = uipanel(app.UIFigure);
            app.AmountHourPanel.Title = 'Amount/Hour';
            app.AmountH = uilabel(app.AmountHourPanel);
            app.AmountH.Text = '';
            app.BaselinePKPanel = uipanel(app.UIFigure);
            app.BaselinePKPanel.Title = 'BaselinePK';
            app.BaselinePK = uilabel(app.BaselinePKPanel);
            app.BaselinePK.Text = '';
            app.BolusPKPanel = uipanel(app.UIFigure);
            app.BolusPKPanel.Title = 'BolusPK';
            app.BolusPK = uilabel(app.BolusPKPanel);
            app.BolusPK.Text = '';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhysicianUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end